classdef GUIlab5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        min0sLabel                     matlab.ui.control.Label
        TiempodelaultimaaccionLabel    matlab.ui.control.Label
        UltimaaccionLabel              matlab.ui.control.Label
        UltimaaccinrealizadaLabel      matlab.ui.control.Label
        CargadaLabel                   matlab.ui.control.Label
        EstadodelaherremientaLabel     matlab.ui.control.Label
        PARADAEMERGENCIAButton         matlab.ui.control.Button
        DEJARHERRAMIENTAButton         matlab.ui.control.Button
        ESCRIBIRINICIALESButton        matlab.ui.control.Button
        DIBUJARESPACIODETRABAJOButton  matlab.ui.control.Button
        RECOGERHERRAMIENTAButton       matlab.ui.control.Button
        VICTORDAVILAMANUELCARRANZALabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        q1 % Description
        q2
        q3
        q4

        % Control table address
        ADDR_MX_TORQUE_ENABLE       = 24;           % Control table address is different in Dynamixel model
        ADDR_MX_GOAL_POSITION       = 30;
        ADDR_MX_VEL                 = 32;
        ADDR_MX_PRESENT_POSITION    = 36;
        
        % Data Byte Length
        LEN_MX_GOAL_POSITION        = 2;
        LEN_MX_PRESENT_POSITION     = 2;
        
        % Protocol version
        PROTOCOL_VERSION            = 1.0;          % See which protocol version is used in the Dynamixel
        
        % Default setting
        DXL_ID                     = [1 2 3 4 5];            % Dynamixel#1 ID: 1 2 3 4
        
        Wspace = [807 87 608 537;
                  244 87 608 537;
                  807 255 100 861;
                  234 255 100 861]
        cherr  = [907 284 78 860;
                  907 284 191 770;
                  907 391 164 711]
        dherr  = [907 284 78 860;
                  907 319 176 744;
                  907 391 164 711]
        BAUDRATE                    = 1000000;
        DEVICENAME                  = 'COM10';       % Check which port is being used on your controller
                                                    % ex) Windows: 'COM1'   Linux: '/dev/ttyUSB0' Mac: '/dev/tty.usbserial-*'
        
        TORQUE_ENABLE               = 1;            % Value for enabling the torque
        TORQUE_DISABLE              = 0;            % Value for disabling the torque
        DXL_MINIMUM_POSITION_VALUE  = 500;          % Dynamixel will rotate between this value
        DXL_MAXIMUM_POSITION_VALUE  = 550;         % and this value (note that the Dynamixel would not move when the position value is out of movable range. Check e-manual about the range of the Dynamixel you use.)
        DXL_MOVING_STATUS_THRESHOLD = 10;           % Dynamixel moving status threshold
        
        ESC_CHARACTER               = 'e';          % Key for escaping loop
        
        COMM_SUCCESS                = 0;            % Communication Success result value
        COMM_TX_FAIL                = -1001;        % Communication Tx Failed
        port_num ;
        group_num;
        velgroup_num;
        dxl_error = 0;                              % Dynamixel error
        dxl1_present_position = 0;                  % Present position
        dxl2_present_position = 0;
        dxl3_present_position = 0;
        dxl4_present_position = 0;
        dxl_comm_result;
        dxl_addparam_result;
        dxl_goal_position;
        maxVel = 114; %rpm maximas del motor
        cerrada=850;
        abierta=512;
        ROBOT
    end
    
    methods (Access = public)
        
        function bitsval = mapeo(app,ang)
            ang2=ang+150;
            bitsval=round(ang2.*1024/300);
        end

        function angval = inversemap(app, bitsval)
            bits2=bitsval*300/1024;
            angval=bits2-150;
        end
        function torques(app, id)
            write1ByteTxRx(app.port_num, app.PROTOCOL_VERSION, id, app.ADDR_MX_TORQUE_ENABLE, app.TORQUE_ENABLE);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            app.dxl_error = getLastRxPacketError(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            elseif app.dxl_error ~= 0
                fprintf('%s\n', getRxPacketError(app.PROTOCOL_VERSION, app.dxl_error));
            else
                fprintf('Dynamixel has been successfully connected \n');
            end
        end
        function velocidades(app, vel)

            lib_name='dxl_x64_c';
            if ~libisloaded(lib_name);
                [notfound, warnings] = loadlibrary('dxl_x64_c', 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_sync_write.h');
            end
            for i=1:4
                
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.velgroup_num, app.DXL_ID(i), vel, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite vel addparam failed', app.DXL_ID(i));
                    return;
                end
            end
            groupSyncWriteTxPacket(app.velgroup_num);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            end
            groupSyncWriteClearParam(app.group_num);
        end

        function velbit= mapvel(app, vel)
            velrpm=vel*(60/1)*(1/(2*pi));
            velbit=round(1023*velrpm/app.maxVel);

        end

        function movermotores(app, qs, vel)
            veluse=mapvel(app, vel);
            velocidades(app, veluse)
            for i=1:4
                
                q = mapeo(app,deg2rad(qs(i)));
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL_ID(i), q, app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite pos addparam failed', app.DXL_ID(i));
                    return;
                end
            end
            groupSyncWriteTxPacket(app.group_num);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            end
            groupSyncWriteClearParam(app.group_num);
        end
        function movergarra(app, val)
            app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL_ID(5), val, app.LEN_MX_GOAL_POSITION);
            if app.dxl_addparam_result ~= true
                fprintf('[ID:%03d] groupSyncWrite pos addparam failed', app.DXL_ID(5));
                return;
            end
            groupSyncWriteTxPacket(app.group_num);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            end
            groupSyncWriteClearParam(app.group_num);
        end
        
        function movermotoresbits(app, qs, vel)

            
            lib_name='dxl_x64_c';
            if ~libisloaded(lib_name);
                [notfound, warnings] = loadlibrary('dxl_x64_c', 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_sync_write.h');
            end
            velocidades(app, vel)
            for i=1:4
                
                %q = mapeo(app,qs(i)*180/pi);
                
                app.dxl_addparam_result = groupSyncWriteAddParam(app.group_num, app.DXL_ID(i), qs(i), app.LEN_MX_GOAL_POSITION);
                if app.dxl_addparam_result ~= true
                    fprintf('[ID:%03d] groupSyncWrite addparam failed', app.DXL_ID(i));
                    return;
                end
            end
            groupSyncWriteTxPacket(app.group_num);
            app.dxl_comm_result = getLastTxRxResult(app.port_num, app.PROTOCOL_VERSION);
            if app.dxl_comm_result ~= app.COMM_SUCCESS
                fprintf('%s\n', getTxRxResult(app.PROTOCOL_VERSION, app.dxl_comm_result));
            end
            groupSyncWriteClearParam(app.group_num);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            lib_name='dxl_x64_c';
            if ~libisloaded(lib_name);
                [notfound, warnings] = loadlibrary('dxl_x64_c', 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_sync_write.h');
            end
            app.port_num = portHandler(app.DEVICENAME);
            app.q1 = 0;
            app.q2 = pi/2;
            app.q3 = 0;
            app.q4 = 0;
            
            
            %app.UITable.Data=["q1",app.q1;"q2",app.q2-pi/2;"q3",app.q3;"q4",app.q4;];
            packetHandler();
            app.group_num = groupSyncWrite(app.port_num, app.PROTOCOL_VERSION, app.ADDR_MX_GOAL_POSITION, app.LEN_MX_GOAL_POSITION);
            app.velgroup_num = groupSyncWrite(app.port_num, app.PROTOCOL_VERSION, app.ADDR_MX_VEL, app.LEN_MX_GOAL_POSITION);
            app.dxl_comm_result = app.COMM_TX_FAIL;             % Communication result
            app.dxl_addparam_result = false;                % AddParam result
            app.dxl_goal_position = [app.DXL_MINIMUM_POSITION_VALUE app.DXL_MAXIMUM_POSITION_VALUE];         % Goal position
            if (openPort(app.port_num))
                fprintf('Succeeded to open the port!\n');
            else
                unloadlibrary(lib_name);
                fprintf('Failed to open the port!\n');
                input('Press any key to terminate...\n');
                return;
            end
            if (setBaudRate(app.port_num, app.BAUDRATE))
                fprintf('Succeeded to change the baudrate!\n');
            else
                unloadlibrary(lib_name);
                fprintf('Failed to change the baudrate!\n');
                input('Press any key to terminate...\n');
                return;
            end
            for i = 1:5
                torques( app, app.DXL_ID(i));
            end
            
        end

        % Button pushed function: RECOGERHERRAMIENTAButton
        function RECOGERHERRAMIENTAButtonPushed(app, event)
            movermotoresbits(app,[512 512 512 512], 20);
            pause(10);
            movergarra(app,app.abierta);
            pause(1);
            movermotoresbits(app,app.cherr(1,:), 20);
            pause(5);
            movermotoresbits(app,app.cherr(2,:), 20);
            pause(5);
            movergarra(app,app.cerrada);
            pause(1);
            movermotoresbits(app,app.cherr(3,:), 20);
            pause(5);
            movermotoresbits(app,[512 512 512 512], 20);
            pause(8);
        end

        % Button pushed function: DIBUJARESPACIODETRABAJOButton
        function DIBUJARESPACIODETRABAJOButtonPushed(app, event)
            movermotoresbits(app,[512 512 512 512], 20);
            pause(5);
            movermotoresbits(app,app.Wspace(1,:), 20);
            pause(15);
            movermotoresbits(app,app.Wspace(2,:), 20);
            pause(15);
            movermotoresbits(app,app.Wspace(4,:), 20);
            pause(15);
            movermotoresbits(app,app.Wspace(3,:), 20);
            pause(15);
            movermotoresbits(app,[512 512 512 512], 20);
            pause(15);
            

        end

        % Button pushed function: ESCRIBIRINICIALESButton
        function ESCRIBIRINICIALESButtonPushed(app, event)
            movermotoresbits(app,[512 512 512 512], 20);
            pause(10);
            
            % Circulo
            movermotoresbits(app,[657 243 208 773], 20);
            pause(8);
            movermotoresbits(app,[687 234 202 800], 20);
            pause(3);
            movermotoresbits(app,[697 245 166 814], 20);
            pause(3);
            movermotoresbits(app,[696 243 131 847], 20);
            pause(3);
            movermotoresbits(app,[682 245 108 861], 20);
            pause(3);
            movermotoresbits(app,[659 242 117 861], 20);
            pause(3);
            movermotoresbits(app,[643 239 157 840], 20);
            pause(3);
            movermotoresbits(app,[649 237 205 789], 20);
            pause(3);
            movermotoresbits(app,[657 243 208 773], 20);
            pause(3);
            movermotoresbits(app,[512 512 512 512], 20);
            pause(10);

            % V
            movermotoresbits(app,[612 237 250 734], 20);
            pause(10);
            movermotoresbits(app,[607 252 165 803], 20);
            pause(3);
            movermotoresbits(app,[590 249 104 861], 20);
            pause(3);
            movermotoresbits(app,[569 240 163 834], 20);
            pause(3);
            movermotoresbits(app,[555 222 245 783], 20);
            pause(3);
            movermotoresbits(app,[512 512 512 512], 20);
            pause(10);

            % M
            movermotoresbits(app,[532 252 91 861], 20);
            pause(10);
            movermotoresbits(app,[526 231 154 861], 20);
            pause(3);
            movermotoresbits(app,[526 219 233 807], 20);
            pause(3);
            movermotoresbits(app,[494 222 176 860], 20);
            pause(3);
            movermotoresbits(app,[472 218 241 802], 20);
            pause(3);
            movermotoresbits(app,[463 225 173 858], 20);
            pause(3);
            movermotoresbits(app,[449 245 107 861], 20);
            pause(3);
            movermotoresbits(app,[512 512 512 512], 20);
            pause(10);

            % Triangulo
            movermotoresbits(app,[406 210 249 810], 20);
            pause(10);
            movermotoresbits(app,[384 219 212 830], 20);
            pause(3);
            movermotoresbits(app,[358 239 168 829], 20);
            pause(3);
            movermotoresbits(app,[382 237 141 856], 20);
            pause(3);
            movermotoresbits(app,[411 240 129 857], 20);
            pause(3);
            movermotoresbits(app,[411 230 180 837], 20);
            pause(3);
            movermotoresbits(app,[406 210 249 810], 20);
            pause(3);

            movermotoresbits(app,[512 512 512 512], 20);
            pause(10);

        end

        % Button pushed function: DEJARHERRAMIENTAButton
        function DEJARHERRAMIENTAButtonPushed(app, event)
            movermotoresbits(app,[512 512 512 512], 20);
            pause(5);
            movermotoresbits(app,app.dherr(3,:), 20);
            pause(5);
            movermotoresbits(app,app.dherr(2,:), 20);
            pause(5);
            movergarra(app,app.abierta);
            pause(1);
            movermotoresbits(app,app.dherr(1,:), 20);
            pause(5);
            movermotoresbits(app,[512 512 512 512], 20);
            pause(10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VICTORDAVILAMANUELCARRANZALabel = uilabel(app.UIFigure);
            app.VICTORDAVILAMANUELCARRANZALabel.Text = 'VICTOR DAVILA, MANUEL CARRANZA';
            app.RECOGERHERRAMIENTAButton = uibutton(app.UIFigure, 'push');
            app.RECOGERHERRAMIENTAButton.ButtonPushedFcn = createCallbackFcn(app, @RECOGERHERRAMIENTAButtonPushed, true);
            app.RECOGERHERRAMIENTAButton.Text = 'RECOGER HERRAMIENTA';
            app.DIBUJARESPACIODETRABAJOButton = uibutton(app.UIFigure, 'push');
            app.DIBUJARESPACIODETRABAJOButton.ButtonPushedFcn = createCallbackFcn(app, @DIBUJARESPACIODETRABAJOButtonPushed, true);
            app.DIBUJARESPACIODETRABAJOButton.Text = 'DIBUJAR ESPACIO DE TRABAJO';
            app.ESCRIBIRINICIALESButton = uibutton(app.UIFigure, 'push');
            app.ESCRIBIRINICIALESButton.ButtonPushedFcn = createCallbackFcn(app, @ESCRIBIRINICIALESButtonPushed, true);
            app.ESCRIBIRINICIALESButton.Text = 'ESCRIBIR INICIALES';
            app.DEJARHERRAMIENTAButton = uibutton(app.UIFigure, 'push');
            app.DEJARHERRAMIENTAButton.ButtonPushedFcn = createCallbackFcn(app, @DEJARHERRAMIENTAButtonPushed, true);
            app.DEJARHERRAMIENTAButton.Text = 'DEJAR HERRAMIENTA';
            app.PARADAEMERGENCIAButton = uibutton(app.UIFigure, 'push');
            app.PARADAEMERGENCIAButton.Text = 'PARADA EMERGENCIA';
            app.EstadodelaherremientaLabel = uilabel(app.UIFigure);
            app.EstadodelaherremientaLabel.Text = 'Estado de la herremienta:';
            app.CargadaLabel = uilabel(app.UIFigure);
            app.CargadaLabel.Text = 'Cargada';
            app.UltimaaccinrealizadaLabel = uilabel(app.UIFigure);
            app.UltimaaccinrealizadaLabel.Text = 'Ultima acción realizada:';
            app.UltimaaccionLabel = uilabel(app.UIFigure);
            app.UltimaaccionLabel.Text = 'Ultima accion';
            app.TiempodelaultimaaccionLabel = uilabel(app.UIFigure);
            app.TiempodelaultimaaccionLabel.Text = 'Tiempo de la ultima accion: ';
            app.min0sLabel = uilabel(app.UIFigure);
            app.min0sLabel.Text = '0 min 0 s';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIlab5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end